classdef pop_channel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        AddButton_2                   matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        CurrentchannelTextArea        matlab.ui.control.TextArea
        CurrentchannelTextAreaLabel   matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        ConfirmButton                 matlab.ui.control.Button
        SinglalossdBSpinner           matlab.ui.control.Spinner
        SinglalossdBSpinnerLabel      matlab.ui.control.Label
        GaussiannoiseSNRSpinner       matlab.ui.control.Spinner
        GaussiannoiseSNRSpinnerLabel  matlab.ui.control.Label
        WILLCHANGELabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp                  ltelab
        LTE                         LTE_dataframe
        channelToAdd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, LTE)
            app.CallingApp = CallingApp;
            app.LTE = LTE;
            app.channelToAdd = NaN(1);
            for i = 1:numel(LTE.channel)
                text = sprintf('%s > ', (LTE.channel{i})); % TODO
                app.CurrentchannelTextArea.Value = ...
                    [app.CurrentchannelTextArea.Value, text];
            end
        end

        % Button pushed function: CancelButton
        function cb_cancel(app, event)
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function cb_confrm(app, event)
            app.LTE = app.LTE.addChannel(app.channelToAdd);
            app.CallingApp.updateLTE(app.LTE);
            delete(app);
        end

        % Button pushed function: AddButton
        function cb_gauss(app, event)
            SNR = app.GaussiannoiseSNRSpinner.Value;
            app.channelToAdd = @(x)(awgn(x,SNR,'measured'));
        end

        % Button pushed function: AddButton_2
        function cb_loss(app, event)
            lossDB = app.SinglalossdBSpinner.Value;
            app.channelToAdd  = @(x)(x .* 10^(-lossDB/20));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WILLCHANGELabel = uilabel(app.GridLayout);
            app.WILLCHANGELabel.Text = 'WILL CHANGE';
            app.GaussiannoiseSNRSpinnerLabel = uilabel(app.GridLayout);
            app.GaussiannoiseSNRSpinnerLabel.Text = 'Gaussian noise (SNR)';
            app.GaussiannoiseSNRSpinner = uispinner(app.GridLayout);
            app.SinglalossdBSpinnerLabel = uilabel(app.GridLayout);
            app.SinglalossdBSpinnerLabel.Text = 'Singla loss (dB)';
            app.SinglalossdBSpinner = uispinner(app.GridLayout);
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @cb_confrm, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.CurrentchannelTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentchannelTextAreaLabel.Text = 'Current channel';
            app.CurrentchannelTextArea = uitextarea(app.GridLayout);
            app.CurrentchannelTextArea.Editable = 'off';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @cb_gauss, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.GridLayout, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @cb_loss, true);
            app.AddButton_2.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_channel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end