classdef pop_importaudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CarrierfrequencykHzEditField    matlab.ui.control.NumericEditField
        CarrierfrequencykHzEditFieldLabel  matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CurrentpathLabel                matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        ImportfileLabel                 matlab.ui.control.Label
        BitperframebpsfsEditField       matlab.ui.control.NumericEditField
        BitperframebpsfsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        path
        selected
        callingApp
        LTE
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, LTE)
            app.callingApp = callingApp;
            app.LTE = LTE;
            app.selected = false;
        end

        % Button pushed function: ImportButton
        function cb_import(app, event)
            [file ,filepath] = uigetfile({'*.wav'},...
                          'File Selector');
            if ~isequal(file,0)
               app.path = fullfile(filepath,file);
               app.selected = true;
               app.ConfirmButton.Enable = 'on';
               app.Label.Text = app.path;
            end
        end

        % Button pushed function: CancelButton
        function cb_cancel(app, event)
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function cb_confirm(app, event)
            if ~app.selected
                pop_error('please select a file or cancel operation')
                return
            end
            
            bitrate = app.BitperframebpsfsEditField.Value;
            [digitalData, fs] = AudioFactory.audioToDigital(app.path, bitrate);
            
            limit = 10000;
            [digitalData, fs] = AudioFactory.downSample(digitalData, fs, limit);

            name = app.NameEditField.Value;
            carrier = 1000 * app.CarrierfrequencykHzEditField.Value;

            app.LTE = app.LTE.setData(digitalData, -1);
            app.LTE.fs = fs;
            app.LTE.bps = fs * bitrate;
            app.LTE.name = name;
            app.LTE = app.LTE.setCarrierFreq(carrier);

            app.callingApp.updateLTE(app.LTE);
            app.callingApp.setToolbarState("on","on","on");

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BitperframebpsfsEditFieldLabel = uilabel(app.GridLayout);
            app.BitperframebpsfsEditFieldLabel.Text = 'Bit per frame ( bps / fs )';
            app.BitperframebpsfsEditField = uieditfield(app.GridLayout, 'numeric');
            app.BitperframebpsfsEditField.Limits = [1 Inf];
            app.BitperframebpsfsEditField.ValueDisplayFormat = '%.0f';
            app.BitperframebpsfsEditField.Value = 1;
            app.ImportfileLabel = uilabel(app.GridLayout);
            app.ImportfileLabel.Text = 'Import file';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @cb_import, true);
            app.ImportButton.Text = 'Import';
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @cb_confirm, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.CurrentpathLabel = uilabel(app.GridLayout);
            app.CurrentpathLabel.Text = 'Current path';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '-';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.CarrierfrequencykHzEditFieldLabel = uilabel(app.GridLayout);
            app.CarrierfrequencykHzEditFieldLabel.Text = 'Carrier frequency (kHz)';
            app.CarrierfrequencykHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.CarrierfrequencykHzEditField.Limits = [1 Inf];
            app.CarrierfrequencykHzEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_importaudio(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end