classdef pop_importrandomdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CarrierfrequencykHzSpinner     matlab.ui.control.Spinner
        CarrierfrequencykHzSpinnerLabel  matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        OkButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        NumberofchannelsSpinner        matlab.ui.control.Spinner
        NumberofchannelsSpinnerLabel   matlab.ui.control.Label
        SignaldurationsecSpinner       matlab.ui.control.Spinner
        SignaldurationsecSpinnerLabel  matlab.ui.control.Label
        Starttime0defaultSpinner       matlab.ui.control.Spinner
        StarttimeLabel                 matlab.ui.control.Label
        BitratebpsSpinner              matlab.ui.control.Spinner
        BitratebpsSpinnerLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp          ltelab
        LTE                 LTE_dataframe
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, LTE)
            app.CallingApp = CallingApp;
            app.LTE = LTE;
        end

        % Button pushed function: OkButton
        function cb_ok(app, event)
            bps = app.BitratebpsSpinner.Value;
            carrier = 1000 * app.CarrierfrequencykHzSpinner.Value;
            % start = app.Starttime0defaultSpinner.Value;
            duration = app.SignaldurationsecSpinner.Value;
            % channels = app.NumberofchannelsSpinner.Value;
            data = randi([0, 1], floor(duration*bps), 1);
            app.LTE = app.LTE.setData(data, bps);
            app.LTE.bps = bps;
            app.LTE.name = app.NameEditField.Value;
            app.LTE = app.LTE.setCarrierFreq(carrier);
            % update LTE from main app
            app.CallingApp.updateLTE(app.LTE);
            app.CallingApp.setToolbarState("on","on","on");
            delete(app);
        end

        % Button pushed function: CancelButton
        function cb_close(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BitratebpsSpinnerLabel = uilabel(app.GridLayout);
            app.BitratebpsSpinnerLabel.Text = 'Bitrate (bps)';
            app.BitratebpsSpinner = uispinner(app.GridLayout);
            app.BitratebpsSpinner.Limits = [0 Inf];
            app.BitratebpsSpinner.ValueDisplayFormat = '%.0f';
            app.StarttimeLabel = uilabel(app.GridLayout);
            app.StarttimeLabel.Text = 'Start time (0 -> default)';
            app.Starttime0defaultSpinner = uispinner(app.GridLayout);
            app.Starttime0defaultSpinner.Limits = [0 Inf];
            app.Starttime0defaultSpinner.ValueDisplayFormat = '%.0f';
            app.SignaldurationsecSpinnerLabel = uilabel(app.GridLayout);
            app.SignaldurationsecSpinnerLabel.Text = 'Signal duration (sec)';
            app.SignaldurationsecSpinner = uispinner(app.GridLayout);
            app.SignaldurationsecSpinner.Limits = [0 Inf];
            app.NumberofchannelsSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofchannelsSpinnerLabel.Text = 'Number of channels';
            app.NumberofchannelsSpinner = uispinner(app.GridLayout);
            app.NumberofchannelsSpinner.Limits = [1 Inf];
            app.NumberofchannelsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofchannelsSpinner.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_close, true);
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.GridLayout2, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @cb_ok, true);
            app.OkButton.Text = 'Ok';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.CharacterLimits = [0 24];
            app.NameEditField.Placeholder = 'rnd';
            app.CarrierfrequencykHzSpinnerLabel = uilabel(app.GridLayout);
            app.CarrierfrequencykHzSpinnerLabel.Text = 'Carrier frequency (kHz)';
            app.CarrierfrequencykHzSpinner = uispinner(app.GridLayout);
            app.CarrierfrequencykHzSpinner.Limits = [0 Inf];
            app.CarrierfrequencykHzSpinner.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_importrandomdata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end