classdef pop_plotdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        GridLayout2  matlab.ui.container.GridLayout
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        CloseButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LTE
        k
        limit
    end
    
    methods (Access = private)
        
        function plot_data(app, k)
            k = k * app.limit;
            data = app.LTE.data(1 + k:(app.limit+k));
            % Clear the existing axes
            cla(app.UIAxes);
            
            % Calculate the baseline value for app.LTE.data
            baseline = -0.5;
            
            % Plot app.LTE.data as a stem plot with a custom baseline
            stem(app.UIAxes, data, ...
                'MarkerSize', 5, 'Marker', 'o', 'BaseValue', baseline, ...
                'LineStyle', 'none', 'Color', 'b' ...
            );
            
            hold(app.UIAxes, 'on'); % Hold the current plot
            
            % Check if app.LTE.receivedData is not NaN
            if ~isnan(app.LTE.receivedData)
                % Calculate the baseline value for app.LTE.receivedData
                baselineReceived = -0.5;
                recievedData = app.LTE.receivedData(1 + k:(app.limit+k));
                % Plot app.LTE.receivedData as a stem plot with a custom baseline
                stem(app.UIAxes, recievedData, ...
                    'MarkerSize', 5, 'Marker', 'x', 'BaseValue', baselineReceived, ...
                    'LineStyle', 'none', 'Color', 'r' ...
                );
            end
            
            hold(app.UIAxes, 'off'); % Release the plot hold
            
            % Customize plot appearance
            title(app.UIAxes, 'LTE Signal Data'); % Add a title
            xlabel(app.UIAxes, 'Sample Index'); % Label for x-axis
            ylabel(app.UIAxes, 'Data Value'); % Label for y-axis
            
            % Set y-axis ticks to 0 and 1 only
            yticks(app.UIAxes, [0, 1]);
            
            % Add grid lines
            grid(app.UIAxes, 'on');
            
            % Adjust font size for better readability
            set(app.UIAxes, 'FontSize', 12);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LTE)
            app.LTE = LTE;
            app.k = 0;
            app.limit = 100;
            app.plot_data(app.k);
        end

        % Button pushed function: CloseButton
        function cb_close(app, event)
            delete(app);
        end

        % Button pushed function: Button_2
        function cb_left(app, event)
            app.k = max(0, app.k-1);
            app.plot_data(app.k);
        end

        % Button pushed function: Button
        function cb_right(app, event)
            app.k = min(floor(numel(app.LTE.data) / app.limit) ,app.k + 1);
            app.plot_data(app.k);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @cb_close, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @cb_right, true);
            app.Button.Text = '>>';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @cb_left, true);
            app.Button_2.Text = '<<';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_plotdata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end