classdef pop_setMod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MatlabvariablePanel            matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        VariablenameEditField          matlab.ui.control.EditField
        VariablenameEditFieldLabel     matlab.ui.control.Label
        ModulatortypeButtonGroup       matlab.ui.container.ButtonGroup
        FrommatlabvariableButton       matlab.ui.control.RadioButton
        StandardButton                 matlab.ui.control.RadioButton
        StandardmodulatorsPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ModulatorDropDown              matlab.ui.control.DropDown
        ModulatorDropDownLabel         matlab.ui.control.Label
        ModulationorderEditField       matlab.ui.control.NumericEditField
        ModulationorderEditFieldLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        LoadButton                     matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        selection
        modulator
        demodulator
        order
        callingApp
        LTE
        modSet
    end
    
    methods (Access = private)
        
        function plotConst(app)
            data = 0:(app.order-1);
            data = int2bit(data, log2(app.order));
            data = reshape(data, [log2(app.order)*app.order 1]);
            modulatedData = app.modulator(data);
            
            % Plot the constellation in the IQ plane
            scatter(app.UIAxes, real(modulatedData), imag(modulatedData), 'filled');
            title(app.UIAxes, 'Constellation Diagram');
            xlabel(app.UIAxes, 'In-Phase (I)');
            ylabel(app.UIAxes, 'Quadrature (Q)');
            
            % Optionally, you can set axis limits to ensure the entire constellation is visible
            % xlim(app.UIAxes, [-1.5, 1.5]); % Adjust the limits as needed
            % ylim(app.UIAxes, [-1.5, 1.5]); % Adjust the limits as needed
        end

        
        function type = selectionToType(~, selection)
            % Define the mapping of selections to modulator types
            selectionToTypeMap = containers.Map( ...
                {'Amplitude shift keying(ASK)', 'Phase shift keying(PSK)', ...
                'Quadrature Amplitude Modulation (QAM)', 'Frequency Shift Keying (FSK)'}, ...
                {'ASK', 'PSK', 'QAM', 'FSK'} ...
            );
        
            % Use the map to get the modulator type based on the selection
            if isKey(selectionToTypeMap, selection)
                type = selectionToTypeMap(selection);
            else
                error('Modulator type not found for the selected value');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, LTE)
            app.callingApp = callingApp;
            app.LTE = LTE;
            app.selection = "standard";
            app.modSet = false;
        end

        % Selection changed function: ModulatortypeButtonGroup
        function cb_selection(app, event)
            selectedButton = app.ModulatortypeButtonGroup.SelectedObject;
            if selectedButton == app.StandardButton
                app.StandardmodulatorsPanel.Enable = 'on';
                app.MatlabvariablePanel.Enable = 'off';
                app.selection = "standard";
            else
                app.StandardmodulatorsPanel.Enable = 'off';
                app.MatlabvariablePanel.Enable = 'on';
                app.selection = "var";
            end
        end

        % Button pushed function: LoadButton
        function cb_load(app, event)
            if app.selection == "standard"
                modulationOrder = app.ModulationorderEditField.Value;
                modulatorType = app.ModulatorDropDown.Value;
                modulatorType = app.selectionToType(modulatorType);
                app.order = modulationOrder;
                disp(modulatorType);
                app.modulator = ModulatorFactory.createModulator(...
                    modulatorType, 'bit', modulationOrder...
                );
                app.demodulator = ModulatorFactory.createDemodulator(...
                    modulatorType, 'bit', modulationOrder...
                );
                app.modSet = true;
            elseif app.selection == "var"
                
            end
            app.plotConst()
        end

        % Button pushed function: CancelButton
        function cb_cancel(app, event)
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function cb_confirm(app, event)
            if ~app.modSet
                pop_error("modulation protocol not set");
                return
            end
            app.LTE.modulator = app.modulator;
            app.LTE.demodulator = app.demodulator;
            app.LTE.modulationOrder = app.order;
            app.callingApp.updateLTE(app.LTE);
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {463, 463};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.StandardmodulatorsPanel = uipanel(app.GridLayout2);
            app.StandardmodulatorsPanel.Title = 'Standard modulators';
            app.GridLayout3 = uigridlayout(app.StandardmodulatorsPanel);
            app.ModulationorderEditFieldLabel = uilabel(app.GridLayout3);
            app.ModulationorderEditFieldLabel.Text = 'Modulation order';
            app.ModulationorderEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ModulationorderEditField.Limits = [0 Inf];
            app.ModulatorDropDownLabel = uilabel(app.GridLayout3);
            app.ModulatorDropDownLabel.Text = 'Modulator';
            app.ModulatorDropDown = uidropdown(app.GridLayout3);
            app.ModulatorDropDown.Items = {'None', 'Amplitude shift keying(ASK)', 'Phase shift keying(PSK)', 'Quadrature Amplitude Modulation (QAM)', 'Frequency Shift Keying (FSK)'};
            app.ModulatorDropDown.Value = 'Amplitude shift keying(ASK)';
            app.ModulatortypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.ModulatortypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @cb_selection, true);
            app.ModulatortypeButtonGroup.Title = 'Modulator type';
            app.StandardButton = uiradiobutton(app.ModulatortypeButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.FrommatlabvariableButton = uiradiobutton(app.ModulatortypeButtonGroup);
            app.FrommatlabvariableButton.Text = 'From matlab variable';
            app.MatlabvariablePanel = uipanel(app.GridLayout2);
            app.MatlabvariablePanel.Enable = 'off';
            app.MatlabvariablePanel.Title = 'Matlab variable';
            app.GridLayout4 = uigridlayout(app.MatlabvariablePanel);
            app.VariablenameEditFieldLabel = uilabel(app.GridLayout4);
            app.VariablenameEditFieldLabel.Text = 'Variable name';
            app.VariablenameEditField = uieditfield(app.GridLayout4, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Constelations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ConfirmButton = uibutton(app.GridLayout5, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @cb_confirm, true);
            app.ConfirmButton.Text = 'Confirm';
            app.LoadButton = uibutton(app.GridLayout5, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @cb_load, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_setMod(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end