classdef ltelab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LTELabLTESimulatorAppUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportdataMenu                 matlab.ui.container.Menu
        MatlabArrayMenu                matlab.ui.container.Menu
        AsciifloatfileMenu             matlab.ui.container.Menu
        RandomdataMenu                 matlab.ui.container.Menu
        AudiofileMenu                  matlab.ui.container.Menu
        ExportdataMenu                 matlab.ui.container.Menu
        ExporttomatlabworkspaceMenu    matlab.ui.container.Menu
        ExportasaudioMenu              matlab.ui.container.Menu
        LoadexistingsetMenu            matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        EncodedataMenu                 matlab.ui.container.Menu
        DecodedataMenu                 matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        modulationMenu                 matlab.ui.container.Menu
        SetmodulatordemodulatorMenu_2  matlab.ui.container.Menu
        modulatesignalMenu             matlab.ui.container.Menu
        demodulatesignalMenu           matlab.ui.container.Menu
        transmitionchannelMenu         matlab.ui.container.Menu
        AddremovechannelMenu           matlab.ui.container.Menu
        ApplychannelMenu               matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        PlotdataMenu                   matlab.ui.container.Menu
        PlotsignalMenu                 matlab.ui.container.Menu
        PlotPSKMenu                    matlab.ui.container.Menu
        TimeFrequencytransformsMenu    matlab.ui.container.Menu
        SpectrumanalysisMenu           matlab.ui.container.Menu
        DatasetsMenu                   matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutLTELabMenu                matlab.ui.container.Menu
        LTELabTutorialMenu             matlab.ui.container.Menu
        EmailtheLTELabteamMenu         matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        ModulationOrderNumLabel        matlab.ui.control.Label
        ModulationorderLabel           matlab.ui.control.Label
        ModulatorLabel_3               matlab.ui.control.Label
        SignalLengthNumLabel           matlab.ui.control.Label
        SignallengthmsLabel            matlab.ui.control.Label
        GridLayout_2                   matlab.ui.container.GridLayout
        FileSetNameLabel_2             matlab.ui.control.Label
        FilenameLabel_2                matlab.ui.control.Label
        SamplingRateNumLabel_2         matlab.ui.control.Label
        SamplingrateLabel_2            matlab.ui.control.Label
        ChannelSetLabel_2              matlab.ui.control.Label
        ChannelLabel_2                 matlab.ui.control.Label
        ModulatorNameLabel_2           matlab.ui.control.Label
        ModulatorLabel_2               matlab.ui.control.Label
        FramesNumLabel_2               matlab.ui.control.Label
        FramesLabel_2                  matlab.ui.control.Label
        SourceNumLabel_2               matlab.ui.control.Label
        SourcesLabel_2                 matlab.ui.control.Label
        NodataloadedLabel_2            matlab.ui.control.Label
        FileSetNameLabel               matlab.ui.control.Label
        FilenameLabel                  matlab.ui.control.Label
        BitrateNumLabel                matlab.ui.control.Label
        BitratebpsLabel                matlab.ui.control.Label
        ChannelSetLabel                matlab.ui.control.Label
        ChannelLabel                   matlab.ui.control.Label
        ModulatorNameLabel             matlab.ui.control.Label
        FramesNumLabel                 matlab.ui.control.Label
        FramesLabel                    matlab.ui.control.Label
        SourceNumLabel                 matlab.ui.control.Label
        SourcesLabel                   matlab.ui.control.Label
        NodataloadedLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        LTE = LTE_dataframe();
    end
    
    properties (Access = private)
        DialogApp % Any secondary dialog being called
    end
    
    methods (Access = public)
        
        function updateLTE(app, LTE)
            app.LTE = LTE;
            app.redrawData();
        end

        function redrawData(app)
             app.NodataloadedLabel.Text = app.LTE.name;
             app.SourceNumLabel.Text = sprintf("%d" ,app.LTE.numchannels);
             frames = size(app.LTE.data,1);
             app.FramesNumLabel.Text = sprintf("%d", frames);
             app.ModulatorNameLabel.Text = ''; % TODO
             app.ModulationOrderNumLabel.Text = sprintf("%d", ...
                 app.LTE.modulationOrder);
             channelSet = 'Yes';
             if numel(app.LTE.channel) == 0
                 channelSet = 'No';
             end
             app.ChannelSetLabel.Text = channelSet;
             app.SignalLengthNumLabel.Text = sprintf("%.2f",...
                 1000 * frames / app.LTE.bps);
             app.BitrateNumLabel.Text = sprintf("%d",app.LTE.bps);
             app.FileSetNameLabel.Text = app.LTE.path;
        end

        function setToolbarState(app, edit, Tools, Plot)
            app.EditMenu.Enable = edit;
            app.ToolsMenu.Enable = Tools;
            app.PlotMenu.Enable = Plot;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: RandomdataMenu
        function cb_importrandomdata(app, event)
            app.DialogApp = pop_importrandomdata(app, app.LTE);
        end

        % Menu selected function: QuitMenu
        function cb_quit(app, event)
            delete(app.DialogApp);
            delete(app);
        end

        % Menu selected function: PlotdataMenu
        function cb_plotdata(app, event)
            app.DialogApp = pop_plotdata(app.LTE);
        end

        % Menu selected function: ExporttomatlabworkspaceMenu
        function cb_export(app, event)
            assignin("base","lte",app.LTE);
        end

        % Menu selected function: EmailtheLTELabteamMenu
        function cb_email(app, event)
            email = 'borna710kh@gmail.com';
            url = ['mailto:',email];
            web(url)
        end

        % Menu selected function: PlotPSKMenu
        function cb_plotpsk(app, event)
            if isnan(app.LTE.modulatedData)
                pop_error('data not modulated yet', true);
            else
                plotpsk(app.LTE.modulatedData);
            end
        end

        % Menu selected function: modulatesignalMenu
        function cb_modulate(app, event)
            app.LTE = app.LTE.modulate();
        end

        % Menu selected function: demodulatesignalMenu
        function cb_demodulate(app, event)
            app.LTE = app.LTE.demodulate();
            BER = sum(app.LTE.data ~= app.LTE.receivedData) / length(app.LTE.data);
            BER_percentage = BER * 100; % Convert BER to a percentage
            pop_error(sprintf('BER = %.2f%%', BER_percentage), false);
        end

        % Menu selected function: SetmodulatordemodulatorMenu_2
        function cb_setModulator(app, event)
            disp(app.LTE)
            app.DialogApp = pop_setMod(app, app.LTE);
        end

        % Menu selected function: ApplychannelMenu
        function cb_applyChannel(app, event)
            app.LTE = app.LTE.applyChannel();
        end

        % Menu selected function: AddremovechannelMenu
        function cb_addrmchannel(app, event)
            app.DialogApp = pop_channel(app, app.LTE);
        end

        % Menu selected function: AudiofileMenu
        function cb_audio(app, event)
            app.DialogApp = pop_importaudio(app, app.LTE);
        end

        % Menu selected function: ExportasaudioMenu
        function cb_exportAudio(app, event)
            lte = app.LTE;
            data = lte.receivedData;
            bits = lte.bps/lte.fs;
            bits = round(bits); % to avoid errors
            AudioFactory.digitalToAudio(data, lte.fs, bits, 'recieved.wav');
        end

        % Menu selected function: SpectrumanalysisMenu
        function cb_spectrum(app, event)
            [signal, fs] = app.LTE.getSignal();
            plotspectrum(signal,  fs);
        end

        % Menu selected function: EncodedataMenu
        function cb_encdata(app, event)
            app.LTE = app.LTE.encode();
        end

        % Menu selected function: DecodedataMenu
        function cb_decdata(app, event)
            app.LTE = app.LTE.decode();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LTELabLTESimulatorAppUIFigure = uifigure('Visible', 'off');
            app.LTELabLTESimulatorAppUIFigure.Name = 'LTELab - LTE Simulator App';
            app.FileMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.Text = 'Import data';
            app.MatlabArrayMenu = uimenu(app.ImportdataMenu);
            app.MatlabArrayMenu.Text = 'Matlab Array';
            app.AsciifloatfileMenu = uimenu(app.ImportdataMenu);
            app.AsciifloatfileMenu.Text = 'Ascii/float file';
            app.RandomdataMenu = uimenu(app.ImportdataMenu);
            app.RandomdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_importrandomdata, true);
            app.RandomdataMenu.Text = 'Random data';
            app.AudiofileMenu = uimenu(app.ImportdataMenu);
            app.AudiofileMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_audio, true);
            app.AudiofileMenu.Text = 'Audio file';
            app.ExportdataMenu = uimenu(app.FileMenu);
            app.ExportdataMenu.Text = 'Export data';
            app.ExporttomatlabworkspaceMenu = uimenu(app.ExportdataMenu);
            app.ExporttomatlabworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_export, true);
            app.ExporttomatlabworkspaceMenu.Text = 'Export to matlab workspace';
            app.ExportasaudioMenu = uimenu(app.ExportdataMenu);
            app.ExportasaudioMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_exportAudio, true);
            app.ExportasaudioMenu.Text = 'Export as audio';
            app.LoadexistingsetMenu = uimenu(app.FileMenu);
            app.LoadexistingsetMenu.Text = 'Load existing set';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_quit, true);
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.EncodedataMenu = uimenu(app.EditMenu);
            app.EncodedataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_encdata, true);
            app.EncodedataMenu.Text = 'Encode data';
            app.DecodedataMenu = uimenu(app.EditMenu);
            app.DecodedataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_decdata, true);
            app.DecodedataMenu.Text = 'Decode data';
            app.ToolsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.ToolsMenu.Enable = 'off';
            app.ToolsMenu.Text = 'Tools';
            app.modulationMenu = uimenu(app.ToolsMenu);
            app.modulationMenu.Text = 'modulation';
            app.SetmodulatordemodulatorMenu_2 = uimenu(app.modulationMenu);
            app.SetmodulatordemodulatorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @cb_setModulator, true);
            app.SetmodulatordemodulatorMenu_2.Text = 'Set modulator/demodulator';
            app.modulatesignalMenu = uimenu(app.modulationMenu);
            app.modulatesignalMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_modulate, true);
            app.modulatesignalMenu.Text = 'modulate signal';
            app.demodulatesignalMenu = uimenu(app.modulationMenu);
            app.demodulatesignalMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_demodulate, true);
            app.demodulatesignalMenu.Text = 'demodulate signal';
            app.transmitionchannelMenu = uimenu(app.ToolsMenu);
            app.transmitionchannelMenu.Text = 'transmition channel';
            app.AddremovechannelMenu = uimenu(app.transmitionchannelMenu);
            app.AddremovechannelMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_addrmchannel, true);
            app.AddremovechannelMenu.Text = 'Add/remove channel';
            app.ApplychannelMenu = uimenu(app.transmitionchannelMenu);
            app.ApplychannelMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_applyChannel, true);
            app.ApplychannelMenu.Text = 'Apply channel';
            app.PlotMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.PlotMenu.Enable = 'off';
            app.PlotMenu.Text = 'Plot';
            app.PlotdataMenu = uimenu(app.PlotMenu);
            app.PlotdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_plotdata, true);
            app.PlotdataMenu.Text = 'Plot data';
            app.PlotsignalMenu = uimenu(app.PlotMenu);
            app.PlotsignalMenu.Text = 'Plot signal';
            app.PlotPSKMenu = uimenu(app.PlotMenu);
            app.PlotPSKMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_plotpsk, true);
            app.PlotPSKMenu.Text = 'Plot PSK';
            app.TimeFrequencytransformsMenu = uimenu(app.PlotMenu);
            app.TimeFrequencytransformsMenu.Text = 'Time/Frequency transforms';
            app.SpectrumanalysisMenu = uimenu(app.TimeFrequencytransformsMenu);
            app.SpectrumanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_spectrum, true);
            app.SpectrumanalysisMenu.Text = 'Spectrum analysis';
            app.DatasetsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.DatasetsMenu.Text = 'Datasets';
            app.HelpMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutLTELabMenu = uimenu(app.HelpMenu);
            app.AboutLTELabMenu.Text = 'About LTELab';
            app.LTELabTutorialMenu = uimenu(app.HelpMenu);
            app.LTELabTutorialMenu.Text = 'LTELab Tutorial';
            app.EmailtheLTELabteamMenu = uimenu(app.HelpMenu);
            app.EmailtheLTELabteamMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_email, true);
            app.EmailtheLTELabteamMenu.Text = 'Email the LTELab team';
            app.GridLayout = uigridlayout(app.LTELabLTESimulatorAppUIFigure);
            app.NodataloadedLabel = uilabel(app.GridLayout);
            app.NodataloadedLabel.Text = 'No data loaded';
            app.SourcesLabel = uilabel(app.GridLayout);
            app.SourcesLabel.Text = 'Sources';
            app.SourceNumLabel = uilabel(app.GridLayout);
            app.SourceNumLabel.Text = 'SourceNum';
            app.FramesLabel = uilabel(app.GridLayout);
            app.FramesLabel.Text = 'Frames';
            app.FramesNumLabel = uilabel(app.GridLayout);
            app.FramesNumLabel.Text = 'FramesNum';
            app.ModulatorNameLabel = uilabel(app.GridLayout);
            app.ModulatorNameLabel.Text = 'ModulatorName';
            app.ChannelLabel = uilabel(app.GridLayout);
            app.ChannelLabel.Text = 'Channel';
            app.ChannelSetLabel = uilabel(app.GridLayout);
            app.ChannelSetLabel.Text = 'ChannelSet';
            app.BitratebpsLabel = uilabel(app.GridLayout);
            app.BitratebpsLabel.Text = 'Bitrate (bps)';
            app.BitrateNumLabel = uilabel(app.GridLayout);
            app.BitrateNumLabel.Text = 'BitrateNum';
            app.FilenameLabel = uilabel(app.GridLayout);
            app.FilenameLabel.Text = 'File name';
            app.FileSetNameLabel = uilabel(app.GridLayout);
            app.FileSetNameLabel.Text = 'FileSetName';
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.NodataloadedLabel_2 = uilabel(app.GridLayout_2);
            app.NodataloadedLabel_2.Text = 'No data loaded';
            app.SourcesLabel_2 = uilabel(app.GridLayout_2);
            app.SourcesLabel_2.Text = 'Sources';
            app.SourceNumLabel_2 = uilabel(app.GridLayout_2);
            app.SourceNumLabel_2.Text = 'SourceNum';
            app.FramesLabel_2 = uilabel(app.GridLayout_2);
            app.FramesLabel_2.Text = 'Frames';
            app.FramesNumLabel_2 = uilabel(app.GridLayout_2);
            app.FramesNumLabel_2.Text = 'FramesNum';
            app.ModulatorLabel_2 = uilabel(app.GridLayout_2);
            app.ModulatorLabel_2.Text = 'Modulator';
            app.ModulatorNameLabel_2 = uilabel(app.GridLayout_2);
            app.ModulatorNameLabel_2.Text = 'ModulatorName';
            app.ChannelLabel_2 = uilabel(app.GridLayout_2);
            app.ChannelLabel_2.Text = 'Channel';
            app.ChannelSetLabel_2 = uilabel(app.GridLayout_2);
            app.ChannelSetLabel_2.Text = 'ChannelSet';
            app.SamplingrateLabel_2 = uilabel(app.GridLayout_2);
            app.SamplingrateLabel_2.Text = 'Sampling rate';
            app.SamplingRateNumLabel_2 = uilabel(app.GridLayout_2);
            app.SamplingRateNumLabel_2.Text = 'SamplingRateNum';
            app.FilenameLabel_2 = uilabel(app.GridLayout_2);
            app.FilenameLabel_2.Text = 'File name';
            app.FileSetNameLabel_2 = uilabel(app.GridLayout_2);
            app.FileSetNameLabel_2.Text = 'FileSetName';
            app.SignallengthmsLabel = uilabel(app.GridLayout);
            app.SignallengthmsLabel.Text = 'Signal length (ms)';
            app.SignalLengthNumLabel = uilabel(app.GridLayout);
            app.SignalLengthNumLabel.Text = 'SignalLengthNum';
            app.ModulatorLabel_3 = uilabel(app.GridLayout);
            app.ModulatorLabel_3.Text = 'Modulator';
            app.ModulationorderLabel = uilabel(app.GridLayout);
            app.ModulationorderLabel.Text = 'Modulation order';
            app.ModulationOrderNumLabel = uilabel(app.GridLayout);
            app.ModulationOrderNumLabel.Text = 'ModulationOrderNum';
            % Show the figure after all components are created
            app.LTELabLTESimulatorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ltelab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LTELabLTESimulatorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LTELabLTESimulatorAppUIFigure)
        end
    end
end